classdef convolverbot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ConvolveButton           matlab.ui.control.Button
        KernelDropDown           matlab.ui.control.DropDown
        KernelDropDownLabel      matlab.ui.control.Label
        SignalTypeDropDown       matlab.ui.control.DropDown
        SignalTypeDropDownLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        finalPlot                matlab.ui.control.UIAxes
        combinedPlot             matlab.ui.control.UIAxes
        kernelPlot               matlab.ui.control.UIAxes
        boxcarPlot               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        BoxcarName = 'Pulse'; % Name of boxcar function
        KernelName = 'Gaussian'; % Name of kernel
    end
    
    properties (Access = private)
        boxcar % Actual plot data of boxcar
        kernel % Actual plot data of kernel
        sr = 10 % sample rate in Hz, or step size of 1/x seconds
        duration = 100 % a 100-second signal
        x  % time vector for plotting
        numX % used to keep things the same length
        w % convolution data
        ymax = 1 % temp
        ymin = 0 % temp
    end
    
    methods (Access = private)
        
        function drawBoxcar(app)
            plot(app.boxcarPlot, app.x, app.boxcar)
            % adjust xlim ylim?
        end

        function drawKernel(app)
            plot(app.kernelPlot, app.x(1:numel(app.kernel)), app.kernel);
            % adjust xlim ylim?
        end

        function updateBoxcar(app)
            % Define the actual data for each boxcar
            switch app.BoxcarName
                case '2box'
                    % Like a mix of TwinPeaks and Sustain:
                    % Have an extended signal that happens twice, with a big gap
                    [~, y] = oscillator('square', 1, 2, 1, app.numX-1, .75);
                    y = y + abs(min(y)); % shift up from 0
                case 'Pulse'
                    % A single pulse in the middle
                    % May be hard to see with a 100-second duration...
                    y = zeros([1,app.numX]);
                    y(round(app.numX/2)) = 1;
                case 'TwinPeaks'
                    % two unit spikes at 1/3 and 2/3
                    y = zeros([1,app.numX]);
                    y(round(app.numX/3)) = 1;
                    y(round(2*app.numX/3)) = 1;
                case 'Sustain'
                    % Hold the signal on from 1/3 to 2/3
                    y = zeros([1,app.numX]);
                    y(round(app.numX/3):round(2*app.numX/3)) = 1;
                case 'Sine'
                    [~,y] = oscillator('sine', 1, 2, 1, app.numX-1);
            end % switch
            % Rescale
            y = y / max(y);
            % Save y
            app.boxcar = y;
            % Update convolution, even if we draw later
            updateConv(app);
            % Show what it looks like
            drawBoxcar(app);
        end % updateBoxcar
        
        function updateKernel(app)
            switch app.KernelName
                case 'HRF'
                    app.kernel = twoGammaHrf(30, 1/app.sr);
                case 'Gaussian'
                    app.kernel = normpdf(-3:1/app.sr:3);
                    % Rescale to unit height
                    app.kernel = app.kernel / max(app.kernel);
                case 'Flat'
                    kdur = round(app.numX / 10); % scale it to length of x
                    app.kernel = ones([1,kdur]);
                    app.kernel = app.kernel / kdur; % scale height
                case 'Exponential'
                    kdur = app.x(round(app.numX / 10)); % scale to length of x
                    app.kernel = (0:1/app.sr:kdur) .^ 2;
                    % Rescale to unit height
                    app.kernel = app.kernel / max(app.kernel);
                case 'Edge Detector'
                    app.kernel = [0 1 0 -1 0];
                    % Not sure how well this'll work with such a big signal
            end % switch
            % Update convolution, even if we draw later
            updateConv(app);
            % Update plot
            drawKernel(app);
        end % updateKernel
        
        function updateConv(app)
            % Convolve kernel with boxcar
            app.w = conv(app.boxcar,app.kernel, 'full');
            app.w = app.w(1:app.numX); % truncate to first portion
            app.w = app.w / max(app.w); % scale to unit height
            updateYLims(app);
            % Clear existing convolution plots
            plot(app.combinedPlot, app.x, zeros([1,app.numX]));
            plot(app.finalPlot, app.x, zeros([1,app.numX]));
        end

        function updateYLims(app)
            % Get the max across all data sources
            app.ymax = max([app.boxcar,app.kernel,app.w]);
            app.ymin = min([app.boxcar,app.kernel,app.w]);
            % Add a little buffer
            app.ymax = app.ymax + (.1 * app.ymax);
            app.ymin = app.ymin - abs(.1 * app.ymin);

            % Apply to all plots
            app.boxcarPlot.YLim = [app.ymin app.ymax];
            app.kernelPlot.YLim = [app.ymin app.ymax];
            app.combinedPlot.YLim = [app.ymin app.ymax];
            app.finalPlot.YLim = [app.ymin app.ymax];

            % Also set xlims, just in case
            app.boxcarPlot.XLim = [0 max(app.x)];
            app.kernelPlot.XLim = [0 max(app.x)];
            app.combinedPlot.XLim = [0 max(app.x)];
            app.finalPlot.XLim = [0 max(app.x)];
        end

        function animateConvolution(app)
            % Initialize the animated vectors
            z = zeros(1,app.numX); % animated convolution
            k = z; % animated kernel
            p = [app.boxcar;k];
            h1 = plot(app.combinedPlot, app.x, p);
            h2 = plot(app.finalPlot, app.x, z);

            % Now animate!
            for i = 1:app.numX
                % Get updated data
                % z is an expanding subset of existing w
                z(1:i) = app.w(1:i);
                % k is the kernel, backward, and sliding from left to right
                k = zeros([1,app.numX]); % init
                k(i) = 1; % set a single impulse
                numK = length(app.kernel);
                k = conv(k, fliplr(app.kernel), 'full'); % convert impulse to kernel
                k = k(numK:end); % truncate full convolution to final part
                p = num2cell([app.boxcar;k], 2);
                % Set updated data
                set(h1, {'YData'}, p);
                set(h2, 'YData', z);
                % Draw to screen
                drawnow;
                % Pause to keep it at a particular frame rate
                % 10/numX makes it last 10 seconds, no matter how long x is
                % pause(10/numX);
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % gen some init data to prevent crash
            app.x = 0:1/app.sr:app.duration; % time vector for plotting
            app.numX = numel(app.x);
            app.boxcar = zeros([1,app.numX]);
            app.kernel = zeros([1,round(app.numX/10)]);
            app.w = ones([1,app.numX]);
            % Set values on GUI to stored defaults
            app.SignalTypeDropDown.Value = app.BoxcarName;
            app.KernelDropDown.Value = app.KernelName;
            % Ensure it wraps back around
            updateBoxcar(app);
            updateKernel(app);
            
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            app.BoxcarName = app.SignalTypeDropDown.Value;
            updateBoxcar(app);
        end

        % Value changed function: KernelDropDown
        function KernelDropDownValueChanged(app, event)
            app.KernelName = app.KernelDropDown.Value;
            updateKernel(app);
        end

        % Button pushed function: ConvolveButton
        function ConvolveButtonPushed(app, event)
            animateConvolution(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SignalTypeDropDownLabel = uilabel(app.LeftPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.LeftPanel);
            app.SignalTypeDropDown.Items = {'2box', 'Pulse', 'TwinPeaks', 'Sustain', 'Sine'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Pulse';
            app.KernelDropDownLabel = uilabel(app.LeftPanel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.LeftPanel);
            app.KernelDropDown.Items = {'Flat', 'Exponential', 'Gaussian', 'HRF', 'Edge Detector'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelDropDownValueChanged, true);
            app.KernelDropDown.Value = 'Gaussian';
            app.ConvolveButton = uibutton(app.LeftPanel, 'push');
            app.ConvolveButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolveButtonPushed, true);
            app.ConvolveButton.Text = 'Convolve!';
            app.RightPanel = uipanel(app.GridLayout);
            app.boxcarPlot = uiaxes(app.RightPanel);
            title(app.boxcarPlot, 'Signal')
            xlabel(app.boxcarPlot, 'Time (sec)')
            zlabel(app.boxcarPlot, 'Z')
            app.kernelPlot = uiaxes(app.RightPanel);
            title(app.kernelPlot, 'Kernel')
            xlabel(app.kernelPlot, 'Time (sec)')
            zlabel(app.kernelPlot, 'Z')
            app.combinedPlot = uiaxes(app.RightPanel);
            title(app.combinedPlot, 'Convolution of signal and kernel in action')
            xlabel(app.combinedPlot, 'Time (sec)')
            zlabel(app.combinedPlot, 'Z')
            app.finalPlot = uiaxes(app.RightPanel);
            title(app.finalPlot, 'Result of convolution')
            xlabel(app.finalPlot, 'Time (sec)')
            zlabel(app.finalPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolverbot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end