classdef Filtering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalFiltererUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CutoffSliderHi                  matlab.ui.control.Slider
        ResetButton                     matlab.ui.control.Button
        ShowcutofffrequencySwitch       matlab.ui.control.Switch
        ShowcutofffrequencySwitchLabel  matlab.ui.control.Label
        ShowinputfrequenciesSwitch      matlab.ui.control.Switch
        ShowinputfrequenciesSwitchLabel  matlab.ui.control.Label
        CutoffSlider                    matlab.ui.control.Slider
        LowSlider                       matlab.ui.control.Slider
        LowSliderLabel                  matlab.ui.control.Label
        HighSlider                      matlab.ui.control.Slider
        HighSliderLabel                 matlab.ui.control.Label
        MedSlider                       matlab.ui.control.Slider
        MedSliderLabel                  matlab.ui.control.Label
        FiltertypeButtonGroup           matlab.ui.container.ButtonGroup
        HighpassButton                  matlab.ui.control.RadioButton
        BandpassButton                  matlab.ui.control.RadioButton
        LowpassButton                   matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        InputPlot                       matlab.ui.control.UIAxes
        OutputPlot                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Frequencies = [0.03, 5, 15]; % Input signal frequencies
        Cutoff = [3, 10]; % Lowpass uses 1st val, highpass 2nd, bandpass both
        FilterType = 'lowpass'; % by default
    end
    
    properties (Access = private)
        sr = 8000; % sampling rate of waves to be generated
        dur = 10; % duration (in sec) of waves to be generated
        filterVis = 'Off'; % whether the filter visibility switch is thrown
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            % Call the existing functions with an added 'noplot' flag
            [y, x] = complexSignal(app.Frequencies, app.sr, app.dur, 'noplot');
            filtered = filterComplexSignal(y,x,app.sr, app.FilterType, app.Cutoff, 'noplot');
            plot(app.InputPlot,x,y);
            plot(app.OutputPlot, x, filtered);
            updateYLims(app,y,filtered);
        end

        function updateYLims(app,a,b)
            ymax = max(max(abs([a;b])));
            if ymax == 0
                % Pass some value through, even if everything is 0
                ymax = 1;
            end
            ylims = [-ymax, ymax];
            app.InputPlot.YLim = ylims;
            app.OutputPlot.YLim = ylims;
        end

        function hideInputs(app)
            value = 'Off';
            app.LowSlider.Visible = value;
            app.LowSliderLabel.Visible = value;
            app.MedSlider.Visible = value;
            app.MedSliderLabel.Visible = value;
            app.HighSlider.Visible = value;
            app.HighSliderLabel.Visible = value;
        end
        function hideBothCutoffs(app)
            value = 'Off';
            app.CutoffSlider.Visible = value;
            app.CutoffSliderHi.Visible = value;
        end
        function CutoffAState(app, value)
            % Value should be either 'On' or 'Off'
            app.CutoffSlider.Visible = value;
            
            if strcmp(value, 'On')
                getCutoffFromA(app);
            end
        end
        function CutoffBState(app, value)
            % Value should be either 'On' or 'Off'
            app.CutoffSliderHi.Visible = value;
            if strcmp(value, 'On')
                getCutoffFromB(app);
            end
        end
        function getCutoffFromA(app)
            app.Cutoff(1) = app.CutoffSlider.Value;
            % app.Cutoff(2) = app.CutoffSlider.Value;
        end
        function getCutoffFromB(app)
            app.Cutoff(2) = app.CutoffSliderHi.Value;
        end
        function toggleCutoffSlider(app)
            switch app.filterVis
                case 'On'
                    % Get value AND change what's visible
                    switch app.FilterType
                        case 'lowpass'
                            CutoffAState(app, 'On');
                            CutoffBState(app, 'Off');
                            getCutoffFromA(app);
                        case 'highpass'
                            CutoffAState(app, 'Off');
                            CutoffBState(app, 'On');
                            getCutoffFromB(app);
                        case 'bandpass'
                            CutoffAState(app, 'On');
                            CutoffBState(app, 'On');
                            getCutoffFromA(app);
                            getCutoffFromB(app);
                    end % switch filter type
                case 'Off'
                    % Use some default cutoff values
                    % And DON'T toggle visibility
                    switch app.FilterType
                        case 'lowpass'
                            app.Cutoff = [1 1];
                        case 'highpass'
                            app.Cutoff = [10 10];
                        case 'bandpass'
                            app.Cutoff = [3 10];
                    end % switch filter type

            end % switch visibility
        end % toggleCutoffSlider
        function defaults(app)
            % Properties
            app.Frequencies = [0.03, 5, 15]; % Input signal frequencies
            app.Cutoff = [3, 10]; % Lowpass uses 1st val, highpass 2nd, bandpass both
            app.FilterType = 'lowpass';
            app.filterVis = 'Off';
            % Slider values
            app.CutoffSlider.Value = app.Cutoff(1);
            app.CutoffSliderHi.Value = app.Cutoff(2);
            app.LowSlider.Value = app.Frequencies(1);
            app.MedSlider.Value = app.Frequencies(2);
            app.HighSlider.Value = app.Frequencies(3);
        end
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Visually toggle everything
            app.ShowinputfrequenciesSwitch.Value = 'Off';
            app.ShowcutofffrequencySwitch.Value = 'Off';
            app.LowpassButton.Value = 1;
            % app.BandpassButton = 0;
            % app.HighpassButton = 0;
            % Hide the sliders by default
            hideInputs(app);
            hideBothCutoffs(app);
            % Set default values
            defaults(app);
            % update plots with default values
            updatePlots(app);

        end

        % Selection changed function: FiltertypeButtonGroup
        function FiltertypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FiltertypeButtonGroup.SelectedObject;

            switch selectedButton.Text
                case 'Low-pass'
                    app.FilterType = 'lowpass';
                case 'Bandpass'
                    app.FilterType = 'bandpass';
                case 'High-pass'
                    app.FilterType = 'highpass';
            end
            toggleCutoffSlider(app);
            updatePlots(app);

        end

        % Value changed function: ShowinputfrequenciesSwitch
        function ShowinputfrequenciesSwitchValueChanged(app, event)
            value = app.ShowinputfrequenciesSwitch.Value;
            % Hide or show the Frequency selectors
            app.LowSlider.Visible = value;
            app.LowSliderLabel.Visible = value;
            app.MedSlider.Visible = value;
            app.MedSliderLabel.Visible = value;
            app.HighSlider.Visible = value;
            app.HighSliderLabel.Visible = value;
        end

        % Value changed function: ShowcutofffrequencySwitch
        function ShowcutofffrequencySwitchValueChanged(app, event)
            value = app.ShowcutofffrequencySwitch.Value;
            app.filterVis = value; % update saved property
            switch value
                case 'On'
                    toggleCutoffSlider(app);
                case 'Off'
                    hideBothCutoffs(app);
            end
        end

        % Value changing function: CutoffSlider
        function CutoffSliderValueChanging(app, event)
            changingValue = event.Value;
            % ...no actually just wait for it to stop
        end

        % Value changed function: CutoffSlider
        function CutoffSliderValueChanged(app, event)
            value = app.CutoffSlider.Value;
            getCutoffFromA(app);
            updatePlots(app);
        end

        % Callback function
        function FilterbandSliderValueChanged(app, event)
            value = app.CutoffSliderHi.Value;
            getCutoffFromB(app);
            updatePlots(app);
        end

        % Value changed function: LowSlider
        function LowSliderValueChanged(app, event)
            value = app.LowSlider.Value;
            app.Frequencies(1) = value;
            updatePlots(app);
        end

        % Value changed function: MedSlider
        function MedSliderValueChanged(app, event)
            value = app.MedSlider.Value;
            app.Frequencies(2) = value;
            updatePlots(app);
        end

        % Value changed function: HighSlider
        function HighSliderValueChanged(app, event)
            value = app.HighSlider.Value;
            app.Frequencies(3) = value;
            updatePlots(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Run the startup script, which sets defaults
            startupFcn(app);
        end

        % Value changed function: CutoffSliderHi
        function CutoffSliderHiValueChanged(app, event)
            value = app.CutoffSliderHi.Value;
            getCutoffFromB(app);
            updatePlots(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SignalFiltererUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalFiltererUIFigure = uifigure('Visible', 'off');
            app.SignalFiltererUIFigure.Name = 'Signal Filterer';
            app.SignalFiltererUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SignalFiltererUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FiltertypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.FiltertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltertypeButtonGroupSelectionChanged, true);
            app.FiltertypeButtonGroup.Title = 'Filter type';
            app.LowpassButton = uiradiobutton(app.FiltertypeButtonGroup);
            app.LowpassButton.Text = 'Low-pass';
            app.LowpassButton.Value = true;
            app.BandpassButton = uiradiobutton(app.FiltertypeButtonGroup);
            app.BandpassButton.Text = 'Bandpass';
            app.HighpassButton = uiradiobutton(app.FiltertypeButtonGroup);
            app.HighpassButton.Text = 'High-pass';
            app.MedSliderLabel = uilabel(app.LeftPanel);
            app.MedSliderLabel.Text = 'Med';
            app.MedSlider = uislider(app.LeftPanel);
            app.MedSlider.Limits = [0 5];
            app.MedSlider.ValueChangedFcn = createCallbackFcn(app, @MedSliderValueChanged, true);
            app.MedSlider.Value = 5;
            app.HighSliderLabel = uilabel(app.LeftPanel);
            app.HighSliderLabel.Text = 'High';
            app.HighSlider = uislider(app.LeftPanel);
            app.HighSlider.Limits = [0 15];
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.HighSlider.Value = 15;
            app.LowSliderLabel = uilabel(app.LeftPanel);
            app.LowSliderLabel.Text = 'Low';
            app.LowSlider = uislider(app.LeftPanel);
            app.LowSlider.Limits = [0 1];
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @LowSliderValueChanged, true);
            app.LowSlider.Value = 0.025;
            app.CutoffSlider = uislider(app.LeftPanel);
            app.CutoffSlider.Limits = [0 20];
            app.CutoffSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffSliderValueChanged, true);
            app.CutoffSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffSliderValueChanging, true);
            app.CutoffSlider.Value = 10;
            app.ShowinputfrequenciesSwitchLabel = uilabel(app.LeftPanel);
            app.ShowinputfrequenciesSwitchLabel.Text = 'Show input frequencies';
            app.ShowinputfrequenciesSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowinputfrequenciesSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowinputfrequenciesSwitchValueChanged, true);
            app.ShowcutofffrequencySwitchLabel = uilabel(app.LeftPanel);
            app.ShowcutofffrequencySwitchLabel.Text = 'Show cutoff frequency';
            app.ShowcutofffrequencySwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowcutofffrequencySwitch.ValueChangedFcn = createCallbackFcn(app, @ShowcutofffrequencySwitchValueChanged, true);
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CutoffSliderHi = uislider(app.LeftPanel);
            app.CutoffSliderHi.Limits = [0 20];
            app.CutoffSliderHi.ValueChangedFcn = createCallbackFcn(app, @CutoffSliderHiValueChanged, true);
            app.CutoffSliderHi.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.OutputPlot = uiaxes(app.RightPanel);
            title(app.OutputPlot, 'Filtered signal')
            xlabel(app.OutputPlot, 'Time (sec)')
            ylabel(app.OutputPlot, 'Amplitude')
            zlabel(app.OutputPlot, 'Z')
            app.InputPlot = uiaxes(app.RightPanel);
            title(app.InputPlot, 'Input signal')
            xlabel(app.InputPlot, 'Time (sec)')
            ylabel(app.InputPlot, 'Amplitude')
            zlabel(app.InputPlot, 'Z')
            % Show the figure after all components are created
            app.SignalFiltererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filtering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalFiltererUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalFiltererUIFigure)
        end
    end
end